classdef DataLogging < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataLoggingUIUIFigure   matlab.ui.Figure
        Title                   matlab.ui.control.Label
        Title_4                 matlab.ui.control.Label
        apibutton_2             matlab.ui.control.Button
        Title_5                 matlab.ui.control.Label
        DigitalOutputsPanel     matlab.ui.container.Panel
        label9                  matlab.ui.control.Label
        label6                  matlab.ui.control.Label
        lamp1                   matlab.ui.control.Lamp
        label10                 matlab.ui.control.Label
        label7                  matlab.ui.control.Label
        lamp2                   matlab.ui.control.Lamp
        label11                 matlab.ui.control.Label
        label8                  matlab.ui.control.Label
        lamp3                   matlab.ui.control.Lamp
        ColorCodeTextAreaLabel  matlab.ui.control.Label
        ColorCodeTextArea       matlab.ui.control.TextArea
        apipanel                matlab.ui.container.Panel
        client_id               matlab.ui.control.EditField
        label1                  matlab.ui.control.Label
        client_secret           matlab.ui.control.EditField
        label2                  matlab.ui.control.Label
        label4                  matlab.ui.control.Label
        label5                  matlab.ui.control.Label
        apibutton               matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        VideoLengthPanel        matlab.ui.container.Panel
        EnterlengthofvideotoberecordedLabel  matlab.ui.control.Label
        LengthEditFieldLabel    matlab.ui.control.Label
        recordlength            matlab.ui.control.EditField
        StartButton             matlab.ui.control.Button
        MinutesLabel            matlab.ui.control.Label
        EnterestimatedmanufacturingtimeLabel  matlab.ui.control.Label
        LengthEditFieldLabel_2  matlab.ui.control.Label
        machinelength           matlab.ui.control.EditField
        HoursLabel              matlab.ui.control.Label
        UIAxes2                 matlab.ui.control.UIAxes
        StopButton              matlab.ui.control.Button
        label13                 matlab.ui.control.Label
    end

    
    methods (Access = public)
        function [pin18, pin17, D1, D2, D3] = dataLog(app, fileID)
            %this acts as the arduino values for analog inputs
            
            %records input values
            pin18 = round(90 +5*rand);
            pin17 = round(80+5*rand);
            D1 = 1+round(rand);
            D2 = 1+round(rand);
            D3 = 1+round(rand);
            
            %log analog values
            time = now;
            A = [time; pin17; pin18; D1; D2; D3];
            fprintf(fileID, '%f %.2f %.2f %f %f %f\r\n', A);
        end
    end
    
    methods (Access = private)
        function done = synchVid(app,video_seg)
            %this function performs video synchronization
            c = newline;
            
            % Open Recording
            num=1;
            f = uifigure;
            
            %progress box
            d = uiprogressdlg(f,'Title', 'Synchronizing Video and Data', 'Indeterminate',"on");
            
            for n = 1:video_seg
                v = VideoReader(sprintf('%s %d %s','Machine Run', num, '.mp4'));
                overlay =VideoWriter(sprintf('%s %d %s','Synchronized Machine Run', num, '.mp4'),'MPEG-4');
                open(overlay);
                i=1;
                
                % Open Log File
                fileID = fopen(sprintf('%s %d %s','Dukane Sensor Values', num, '.txt'), 'r');
                formatSpec = '%f %f %f %f %f %f';
                sizeA = [6, Inf];
                A = fscanf(fileID, formatSpec, sizeA);
                fclose(fileID);
                A = A';
                
                fileID = fopen(sprintf('%s %d %s','Dukane Sensor Value Date And Time', num, '.txt'), 'W');
                formatspec = '%s %s %s %s %s %s';
                
                %Specify text position and box color
                position = [30 50; 30 80; 30 110; 30 140; 30 170; 30 200];
                boxColor = {'white', 'white', 'white', 'white', 'white', 'white'};
                
                
                %Begin Synchrnoization
                while hasFrame(v)
                    I = read (v, i);
                    A_time =  datestr(A(i,1));
                    A_pin18 = num2str(A(i,2));
                    A_pin17 = num2str(A(i,3));
                    A_ready = (A(i,4));
                    if A_ready == 1
                        ready = 'on';
                    else
                        ready = 'off';
                    end
                    A_status = num2str(A(i,5)); 
                     if A_status == 1
                        status = 'on';
                    else
                        status = 'off';
                    end
                    A_fault = num2str(A(i,6)); 
                    if A_fault == 1
                        fault = 'on';
                    else
                        fault = 'off';
                    end
                    fprintf(fileID, formatspec, sprintf('%s %s %s %s %s %s', A_time, A_pin18, A_pin17, ready, status, fault, c));
                    dateTime= strcat('Date & Time:', {' '}, A_time);
                    powerOutput = strcat('Welding Power:', {' '}, A_pin17);
                    ampOutput = strcat('Amplitude Output:', {' '}, A_pin18);
                    readyout = strcat('Ready Status:', {' '}, ready);
                    statusout = strcat('Ultrasound Status:', {' '}, status);
                    faultout = strcat('Fault Status:', {' '}, fault);
                    overlayText = [dateTime; powerOutput; ampOutput; readyout; statusout; faultout];
                    img = insertText(I, position, overlayText, 'FontSize', 14, 'BoxColor',....
                        boxColor, 'BoxOpacity', 0.8, 'TextColor', 'black');
                    writeVideo(overlay, img);
                    i = i+1;
                end
               
                close(overlay);
                num=num+1;
            end
             close(d);
             close(f);
            done = true;
        end
        
        function dataDone(app)
            %call this function to turn off visibilty of certain components
            %used for code readability
            app.label10.Visible = 'off';
            delete(app.UIAxes);
            delete(app.UIAxes2);
            app.DigitalOutputsPanel.Visible = 'off';
        end
        
        function apiOn(app)
            app.Title_4.Visible = 'on';
            app.apipanel.Visible = 'on';
        end
        
        function mainfunc(app, length, duration)
            channelID = 979756;
            writekey = 'FH0ARCLT65ZFW9NS';
            timeout = 10;
            web('https://thingspeak.com/channels/979756','-browser');
            %calculate # of video segments to record
            video_seg = 60*duration/length;                 %convert duration to minutes 
            num = 1;                                        %segment counter for video title
            cam = webcam;
            for index = 1:video_seg
                v = VideoWriter(sprintf('%s %d %s','Machine Run', num, '.mp4'), 'MPEG-4');
                fileID = fopen(sprintf('%s %d %s','Dukane Sensor Values', num, '.txt'), 'W'); %initialize log file
                open(v);                                         % open video
                k=1;                                             %counter
                for n = 1:(length*2000)
                    img = snapshot(cam);                             %sample img
                    writeVideo(v,img);                               %write to file
                    [pin18, pin17, D1, D2, D3]=dataLog(app,fileID);  %get power supply outputs and log to file
                    if rem(n, 100) == 0                              %plot periodically
                        updatePlot(app, pin18, pin17, D1, D2, D3);
                    end
                    if rem (n, 500)==0
                        thingSpeakWrite(channelID,[pin18, pin17, D1, D2, D3],'WriteKey',writekey, 'Timeout', timeout);
                    end
                end
                close(v);       %close video file
                fclose(fileID); %close log file
                num = num+1;
            end
            dataDone(app);
            %notify user that process is complete
            pause(1);
            fig = uifigure;
            selection = uiconfirm(fig, 'Videoing and data logging are complete. Press Ok to begin synchronization. Press Cancel to end process.', 'Process Complete Notification','Icon', 'success');
            close(fig);
            endcheck = strcmp(selection, 'Cancel');
            
            %check if canceled process
            if endcheck == 1
                delete(app);
            else
                synchVid(app, video_seg);
                pause(1);
                f = uifigure;
                d = uiprogressdlg(f,'Title', 'Navigating to Google Drive', 'Indeterminate',"on");
                web('https://drive.google.com/open?id=1lsNDKYgKbrZmdnUgLfE9-xRMnRV-dJrB','-browser');
                close(d);
                close(f);
                app.label13.Visible = 'on';
                pause(2);
                delete(app);
            end
        end
        
        function updatePlot(app, pin18, pin17, D1, D2, D3)
            % get current timefrom timer's event data
            tnow = datetime('now');
            
            % Get output values
            y1 = pin18;
            y2 = pin17;
            
            % Plot data and adjust XLim if current time is out of bounds
            xLL = app.UIAxes.XLim(1);
            xUL = app.UIAxes.XLim(2);
            if(tnow > xUL)
                xtra = seconds(5);
                app.UIAxes.XLim = [xLL+xtra tnow+xtra];
                app.UIAxes.XTick = linspace(xLL+xtra, tnow+xtra, 5);
                app.UIAxes2.XLim = [xLL+xtra tnow+xtra];
                app.UIAxes2.XTick = linspace(xLL+xtra, tnow+xtra, 5);
            end
            plot(app.UIAxes, tnow, y1, 'ob');
            plot(app.UIAxes2, tnow, y2, 'ob');
            
            % Adjust YLim to show variations better
            %power plot
            yLL = max(y1-10, 0);
            yUL = y1+ 10;
            yLL2 = max(y2-10, 0);
            yUL2 = y2+ 10;
            app.UIAxes.YLim = [yLL yUL];
            app.UIAxes.YTick = round(linspace(yLL, yUL, 5), 2, 'significant');
            %amplifier plot
            app.UIAxes2.YLim = [yLL2 yUL2];
            app.UIAxes2.YTick = round(linspace(yLL2, yUL2, 5), 2, 'significant');
            
            %update digital output visuals 
            %trigger visuals
            
            if D1 == 0
                app.lamp1.Color = 'r';
            else D1 == 1
                app.lamp1.Color = 'g'; 
            end
            
            if D2 == 0 
                app.lamp2.Color = 'r';
            else 
                app.lamp2.Color = 'g'; 
            end 
            if D3 ==1 
                app.lamp3.Color = 'g';
            else D3 == 0 
                app.lamp3.Color = 'r';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Plot invisible data point to initialize datetime ruler
            plot(app.UIAxes, datetime('now'), 10, 'MarkerEdgeColor', 'none');
            plot(app.UIAxes2, datetime('now'), 10, 'MarkerEdgeColor', 'none');
             
            % Set x limits and ticks for power plot
            t = datetime('now') + seconds(1:10);
            xLL = t(1) - seconds(5);
            xUL = t(length(t));
            app.UIAxes.XLim = [xLL xUL];
            app.UIAxes.XAxis.TickLabelFormat = 'hh:mm:ss';
            app.UIAxes.XTick = linspace(xLL, xUL, 5);
            
            % Set x limits and ticks for amplitude plot
            app.UIAxes2.XLim = [xLL xUL];
            app.UIAxes2.XAxis.TickLabelFormat = 'hh:mm:ss';
            app.UIAxes2.XTick = linspace(xLL, xUL, 5);
            
            % Set y limits and ticks for power plot
            app.UIAxes.YLim = [85 95];
            app.UIAxes.YTick = round(linspace(85, 95, 5), 2, 'significant');
            grid(app.UIAxes,'on');
            hold(app.UIAxes,'on');
            
            app.UIAxes2.YLim = [75 85];
            app.UIAxes2.YTick = round(linspace(75, 85, 5), 2, 'significant');
            grid(app.UIAxes2,'on');
            hold(app.UIAxes2,'on');
            
        end

        % Button pushed function: apibutton_2
        function apibutton_2ButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %get video duration
            length = str2double(app.recordlength.Value);
            duration = str2double(app.machinelength.Value);
            app.VideoLengthPanel.Visible = 'off';
            app.DigitalOutputsPanel.Visible = 'on';
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            app.StopButton.Visible = 'on';
            mainfunc(app,length,duration);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            pause(2);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataLoggingUIUIFigure = uifigure('Visible', 'off');
            app.DataLoggingUIUIFigure.Name = 'Data Logging UI';
            app.Title = uilabel(app.DataLoggingUIUIFigure);
            app.Title.Text = 'Ultrasonic Additive Manufacturing Data Logging';
            app.Title_4 = uilabel(app.DataLoggingUIUIFigure);
            app.Title_4.Visible = 'off';
            app.Title_4.Text = 'Uploading to Google Drive';
            app.apibutton_2 = uibutton(app.DataLoggingUIUIFigure, 'push');
            app.apibutton_2.ButtonPushedFcn = createCallbackFcn(app, @apibutton_2ButtonPushed, true);
            app.apibutton_2.Visible = 'off';
            app.apibutton_2.Text = 'Close Application';
            app.Title_5 = uilabel(app.DataLoggingUIUIFigure);
            app.Title_5.Visible = 'off';
            app.Title_5.Text = 'Files Succesfully Uploaded To Google Drive!';
            app.DigitalOutputsPanel = uipanel(app.DataLoggingUIUIFigure);
            app.DigitalOutputsPanel.Title = 'Digital Outputs';
            app.DigitalOutputsPanel.Visible = 'off';
            app.label9 = uilabel(app.DigitalOutputsPanel);
            app.label9.Text = 'Ready Status Output';
            app.label6 = uilabel(app.DigitalOutputsPanel);
            app.label6.Text = 'Status: ';
            app.lamp1 = uilamp(app.DigitalOutputsPanel);
            app.label10 = uilabel(app.DigitalOutputsPanel);
            app.label10.Text = 'Ultrasound Status Output';
            app.label7 = uilabel(app.DigitalOutputsPanel);
            app.label7.Text = 'Status: ';
            app.lamp2 = uilamp(app.DigitalOutputsPanel);
            app.label11 = uilabel(app.DigitalOutputsPanel);
            app.label11.Text = 'Fault Status Output';
            app.label8 = uilabel(app.DigitalOutputsPanel);
            app.label8.Text = 'Status: ';
            app.lamp3 = uilamp(app.DigitalOutputsPanel);
            app.ColorCodeTextAreaLabel = uilabel(app.DigitalOutputsPanel);
            app.ColorCodeTextAreaLabel.Text = 'Color Code: ';
            app.ColorCodeTextArea = uitextarea(app.DigitalOutputsPanel);
            app.ColorCodeTextArea.Value = {'Green = On '; 'Red = Off'};
            app.apipanel = uipanel(app.DataLoggingUIUIFigure);
            app.apipanel.Title = 'User Input: Cloud Functionality';
            app.apipanel.Visible = 'off';
            app.client_id = uieditfield(app.apipanel, 'text');
            app.label1 = uilabel(app.apipanel);
            app.label1.Text = 'Enter Client ID from Drive API';
            app.client_secret = uieditfield(app.apipanel, 'text');
            app.label2 = uilabel(app.apipanel);
            app.label2.Text = 'Enter Client Secret from Drive API';
            app.label4 = uilabel(app.apipanel);
            app.label4.Text = 'Client ID:';
            app.label5 = uilabel(app.apipanel);
            app.label5.Text = 'Client Secret:';
            app.apibutton = uibutton(app.apipanel, 'push');
            app.apibutton.Text = 'API Keys Entered';
            app.UIAxes = uiaxes(app.DataLoggingUIUIFigure);
            title(app.UIAxes, 'Welding Power Output')
            xlabel(app.UIAxes, 'Machining Duration(s)')
            ylabel(app.UIAxes, 'Power Output (W) ')
            app.UIAxes.Visible = 'off';
            app.VideoLengthPanel = uipanel(app.DataLoggingUIUIFigure);
            app.VideoLengthPanel.Title = 'Video Length';
            app.EnterlengthofvideotoberecordedLabel = uilabel(app.VideoLengthPanel);
            app.EnterlengthofvideotoberecordedLabel.Text = 'Enter length of video to be recorded:';
            app.LengthEditFieldLabel = uilabel(app.VideoLengthPanel);
            app.LengthEditFieldLabel.Text = 'Length: ';
            app.recordlength = uieditfield(app.VideoLengthPanel, 'text');
            app.StartButton = uibutton(app.VideoLengthPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.MinutesLabel = uilabel(app.VideoLengthPanel);
            app.MinutesLabel.Text = 'Minute(s) ';
            app.EnterestimatedmanufacturingtimeLabel = uilabel(app.VideoLengthPanel);
            app.EnterestimatedmanufacturingtimeLabel.Text = 'Enter estimated manufacturing time: ';
            app.LengthEditFieldLabel_2 = uilabel(app.VideoLengthPanel);
            app.LengthEditFieldLabel_2.Text = 'Length: ';
            app.machinelength = uieditfield(app.VideoLengthPanel, 'text');
            app.HoursLabel = uilabel(app.VideoLengthPanel);
            app.HoursLabel.Text = 'Hour(s)';
            app.UIAxes2 = uiaxes(app.DataLoggingUIUIFigure);
            title(app.UIAxes2, 'Weld Amplitude Output')
            xlabel(app.UIAxes2, 'Machining Duration(s)')
            ylabel(app.UIAxes2, 'Amplitude Output (%) ')
            app.UIAxes2.Visible = 'off';
            app.StopButton = uibutton(app.DataLoggingUIUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Visible = 'off';
            app.StopButton.Text = {'Stop'; ''};
            app.label13 = uilabel(app.DataLoggingUIUIFigure);
            app.label13.Visible = 'off';
            app.label13.Text = 'Files Uploaded to Google Drive. Closing Application.';
            % Show the figure after all components are created
            app.DataLoggingUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataLogging

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataLoggingUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataLoggingUIUIFigure)
        end
    end
end