classdef GCodeGen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GenerateCodeButton             matlab.ui.control.Button
        title                          matlab.ui.control.Label
        description                    matlab.ui.control.Label
        description2                   matlab.ui.control.Label
        label11                        matlab.ui.control.Label
        label12                        matlab.ui.control.Label
        UnitsinchesunlessspecifiedotherwiseLabel  matlab.ui.control.Label
        label7_5                       matlab.ui.control.Label
        GCodeUploadedButton            matlab.ui.control.Button
        PartSpecificationsPanel        matlab.ui.container.Panel
        label7                         matlab.ui.control.Label
        partY                          matlab.ui.control.NumericEditField
        PartWidthEditFieldLabel        matlab.ui.control.Label
        label6                         matlab.ui.control.Label
        partX                          matlab.ui.control.NumericEditField
        PartLengthEditFieldLabel       matlab.ui.control.Label
        WorkOffsetZEditFieldLabel_3    matlab.ui.control.Label
        label10_2                      matlab.ui.control.Label
        partHeight                     matlab.ui.control.NumericEditField
        WorkOffsetSpecificationsPanel  matlab.ui.container.Panel
        label9                         matlab.ui.control.Label
        offx                           matlab.ui.control.NumericEditField
        label13                        matlab.ui.control.Label
        label8                         matlab.ui.control.Label
        offy                           matlab.ui.control.NumericEditField
        WorkOffsetXEditFieldLabel      matlab.ui.control.Label
        label10                        matlab.ui.control.Label
        offz                           matlab.ui.control.NumericEditField
        WorkOffsetZEditFieldLabel      matlab.ui.control.Label
        FeedRateSpecificationsPanel    matlab.ui.container.Panel
        addfrlabel                     matlab.ui.control.Label
        feedRate                       matlab.ui.control.NumericEditField
        label1                         matlab.ui.control.Label
        lowerfrlabel                   matlab.ui.control.Label
        lowerRate                      matlab.ui.control.NumericEditField
        label1_2                       matlab.ui.control.Label
        safetravelfr                   matlab.ui.control.Label
        safeTrav                       matlab.ui.control.NumericEditField
        label1_3                       matlab.ui.control.Label
        TapeSpecificationsPanel        matlab.ui.container.Panel
        tapeThick                      matlab.ui.control.NumericEditField
        TapeThicknessLabel             matlab.ui.control.Label
        label3                         matlab.ui.control.Label
        tapeWidth                      matlab.ui.control.NumericEditField
        TapeWidthLabel                 matlab.ui.control.Label
        label2                         matlab.ui.control.Label
        stagger                        matlab.ui.control.NumericEditField
        TapeStaggerLabel               matlab.ui.control.Label
        label2_2                       matlab.ui.control.Label
        OtherSpecificationsPanel       matlab.ui.container.Panel
        compfac                        matlab.ui.control.NumericEditField
        CompressionFactorLabel         matlab.ui.control.Label
        label5                         matlab.ui.control.Label
        label5_2                       matlab.ui.control.Label
        overlapValue                   matlab.ui.control.NumericEditField
        OverlapEditFieldLabel          matlab.ui.control.Label
        label4                         matlab.ui.control.Label
        label7_2                       matlab.ui.control.Label
        PartWidthEditFieldLabel_2      matlab.ui.control.Label
        layers_val                     matlab.ui.control.NumericEditField
        label7_3                       matlab.ui.control.Label
        label7_6                       matlab.ui.control.Label
        springofflabel                 matlab.ui.control.Label
        offSpring                      matlab.ui.control.NumericEditField
        label1_4                       matlab.ui.control.Label
        safeheightlabel                matlab.ui.control.Label
        label1_5                       matlab.ui.control.Label
        safeHeight                     matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateCodeButton
        function GenerateCodeButtonPushed(app, event)
            %get user input
            feed_rate = app.feedRate.Value;
            lower_rate= app.lowerRate.Value;
            safe_trav= app.safeTrav.Value;
            tape_width = app.tapeWidth.Value;
            overlap = app.overlapValue.Value;
            tape_thickness = app.tapeThick.Value;
            part_height = app.partHeight.Value;
            layer_set = app.layers_val.Value;
            x_leng = app.partX.Value;
            y_leng = app.partY.Value;
            comp_fac = app.compfac.Value;
            off_X = app.offx.Value;
            off_Y = app.offy.Value;
            off_Z = app.offz.Value;
            off_Spring = app.offSpring.Value;
            safe_height = app.safeHeight.Value;
            stag = app.stagger.Value; 

            %call GCode.m function and pass values
            [printend]=GCode(feed_rate,tape_width,x_leng,y_leng, overlap, tape_thickness, part_height, comp_fac, layer_set, off_X, off_Y, off_Z, lower_rate, safe_trav, off_Spring, safe_height, stag);
            if printend == 1 
                app.GenerateCodeButton.BackgroundColor = [0.47, 0.86, 0.42];
            end
        end

        % Button pushed function: GCodeUploadedButton
        function GCodeUploadedButtonPushed(app, event)
            app.GCodeUploadedButton.BackgroundColor = [1.00, 0.41, 0.16];
            DataLogging;
            pause(1); 
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GenerateCodeButton = uibutton(app.UIFigure, 'push');
            app.GenerateCodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCodeButtonPushed, true);
            app.GenerateCodeButton.Text = 'Generate Code';
            app.title = uilabel(app.UIFigure);
            app.title.Text = 'G-Code Generation';
            app.description = uilabel(app.UIFigure);
            app.description.Text = 'Enter the following values below, then press the ''Generate Code'' button';
            app.description2 = uilabel(app.UIFigure);
            app.description2.Text = 'Refer to the ReadMe.txt file for documentation ';
            app.label11 = uilabel(app.UIFigure);
            app.label11.Text = 'Click button ONLY when the code has been uploaded to the CNC ';
            app.label12 = uilabel(app.UIFigure);
            app.label12.Text = 'and any errors have been eliminated!';
            app.UnitsinchesunlessspecifiedotherwiseLabel = uilabel(app.UIFigure);
            app.UnitsinchesunlessspecifiedotherwiseLabel.Text = 'Units: inches unless specified otherwise';
            app.label7_5 = uilabel(app.UIFigure);
            app.label7_5.Text = '';
            app.GCodeUploadedButton = uibutton(app.UIFigure, 'push');
            app.GCodeUploadedButton.ButtonPushedFcn = createCallbackFcn(app, @GCodeUploadedButtonPushed, true);
            app.GCodeUploadedButton.Text = 'G-Code Uploaded';
            app.PartSpecificationsPanel = uipanel(app.UIFigure);
            app.PartSpecificationsPanel.Title = 'Part Specifications';
            app.label7 = uilabel(app.PartSpecificationsPanel);
            app.label7.Text = 'Length of part in y direction';
            app.partY = uieditfield(app.PartSpecificationsPanel, 'numeric');
            app.PartWidthEditFieldLabel = uilabel(app.PartSpecificationsPanel);
            app.PartWidthEditFieldLabel.Text = 'Part Width:';
            app.label6 = uilabel(app.PartSpecificationsPanel);
            app.label6.Text = 'Length of part in x direction';
            app.partX = uieditfield(app.PartSpecificationsPanel, 'numeric');
            app.PartLengthEditFieldLabel = uilabel(app.PartSpecificationsPanel);
            app.PartLengthEditFieldLabel.Text = 'Part Length:';
            app.WorkOffsetZEditFieldLabel_3 = uilabel(app.PartSpecificationsPanel);
            app.WorkOffsetZEditFieldLabel_3.Text = 'Part Height:';
            app.label10_2 = uilabel(app.PartSpecificationsPanel);
            app.label10_2.Text = 'Length of part in z direction';
            app.partHeight = uieditfield(app.PartSpecificationsPanel, 'numeric');
            app.WorkOffsetSpecificationsPanel = uipanel(app.UIFigure);
            app.WorkOffsetSpecificationsPanel.Title = 'Work Offset Specifications';
            app.label9 = uilabel(app.WorkOffsetSpecificationsPanel);
            app.label9.Text = 'Enter the work offset x coordinate.';
            app.offx = uieditfield(app.WorkOffsetSpecificationsPanel, 'numeric');
            app.label13 = uilabel(app.WorkOffsetSpecificationsPanel);
            app.label13.Text = 'Work Offset X:';
            app.label8 = uilabel(app.WorkOffsetSpecificationsPanel);
            app.label8.Text = 'Enter the work offset y coordinate.';
            app.offy = uieditfield(app.WorkOffsetSpecificationsPanel, 'numeric');
            app.WorkOffsetXEditFieldLabel = uilabel(app.WorkOffsetSpecificationsPanel);
            app.WorkOffsetXEditFieldLabel.Text = 'Work Offset Y:';
            app.label10 = uilabel(app.WorkOffsetSpecificationsPanel);
            app.label10.Text = 'Enter the work offset z coordinate.';
            app.offz = uieditfield(app.WorkOffsetSpecificationsPanel, 'numeric');
            app.WorkOffsetZEditFieldLabel = uilabel(app.WorkOffsetSpecificationsPanel);
            app.WorkOffsetZEditFieldLabel.Text = 'Work Offset Z:';
            app.FeedRateSpecificationsPanel = uipanel(app.UIFigure);
            app.FeedRateSpecificationsPanel.Title = 'Feed Rate Specifications';
            app.addfrlabel = uilabel(app.FeedRateSpecificationsPanel);
            app.addfrlabel.Text = 'Additive Feed Rate:';
            app.feedRate = uieditfield(app.FeedRateSpecificationsPanel, 'numeric');
            app.label1 = uilabel(app.FeedRateSpecificationsPanel);
            app.label1.Text = 'Tool movement rate for additive passes.';
            app.lowerfrlabel = uilabel(app.FeedRateSpecificationsPanel);
            app.lowerfrlabel.Text = 'Lowering Feed Rate:';
            app.lowerRate = uieditfield(app.FeedRateSpecificationsPanel, 'numeric');
            app.label1_2 = uilabel(app.FeedRateSpecificationsPanel);
            app.label1_2.Text = 'Tool movement rate for lowering sonotrode.';
            app.safetravelfr = uilabel(app.FeedRateSpecificationsPanel);
            app.safetravelfr.Text = 'Safe Travel Feed Rate:';
            app.safeTrav = uieditfield(app.FeedRateSpecificationsPanel, 'numeric');
            app.label1_3 = uilabel(app.FeedRateSpecificationsPanel);
            app.label1_3.Text = 'Tool movement rate for moving sonotrode to safe height.';
            app.TapeSpecificationsPanel = uipanel(app.UIFigure);
            app.TapeSpecificationsPanel.Title = 'Tape Specifications';
            app.tapeThick = uieditfield(app.TapeSpecificationsPanel, 'numeric');
            app.TapeThicknessLabel = uilabel(app.TapeSpecificationsPanel);
            app.TapeThicknessLabel.Text = 'Tape Thickness:';
            app.label3 = uilabel(app.TapeSpecificationsPanel);
            app.label3.Text = 'Enter tape thickness in inches.';
            app.tapeWidth = uieditfield(app.TapeSpecificationsPanel, 'numeric');
            app.TapeWidthLabel = uilabel(app.TapeSpecificationsPanel);
            app.TapeWidthLabel.Text = 'Tape Width:';
            app.label2 = uilabel(app.TapeSpecificationsPanel);
            app.label2.Text = 'Enter tape width in inches. ';
            app.stagger = uieditfield(app.TapeSpecificationsPanel, 'numeric');
            app.TapeStaggerLabel = uilabel(app.TapeSpecificationsPanel);
            app.TapeStaggerLabel.Text = 'Tape Stagger:';
            app.label2_2 = uilabel(app.TapeSpecificationsPanel);
            app.label2_2.Text = 'Enter stagger in inches. ';
            app.OtherSpecificationsPanel = uipanel(app.UIFigure);
            app.OtherSpecificationsPanel.Title = 'Other Specifications';
            app.compfac = uieditfield(app.OtherSpecificationsPanel, 'numeric');
            app.CompressionFactorLabel = uilabel(app.OtherSpecificationsPanel);
            app.CompressionFactorLabel.Text = 'Compression Factor';
            app.label5 = uilabel(app.OtherSpecificationsPanel);
            app.label5.Text = 'Scalar value by which tape thickness will be ';
            app.label5_2 = uilabel(app.OtherSpecificationsPanel);
            app.label5_2.Text = 'compressed by the spring.';
            app.overlapValue = uieditfield(app.OtherSpecificationsPanel, 'numeric');
            app.OverlapEditFieldLabel = uilabel(app.OtherSpecificationsPanel);
            app.OverlapEditFieldLabel.Text = 'Overlap:';
            app.label4 = uilabel(app.OtherSpecificationsPanel);
            app.label4.Text = 'Enter overlap between tape layers.';
            app.label7_2 = uilabel(app.OtherSpecificationsPanel);
            app.label7_2.Text = 'How many layers to print';
            app.PartWidthEditFieldLabel_2 = uilabel(app.OtherSpecificationsPanel);
            app.PartWidthEditFieldLabel_2.Text = 'Layers:';
            app.layers_val = uieditfield(app.OtherSpecificationsPanel, 'numeric');
            app.label7_3 = uilabel(app.OtherSpecificationsPanel);
            app.label7_3.Text = 'before the program is paused for excess removal.';
            app.label7_6 = uilabel(app.OtherSpecificationsPanel);
            app.label7_6.Text = 'Recommended: 10';
            app.springofflabel = uilabel(app.OtherSpecificationsPanel);
            app.springofflabel.Text = 'Spring Offset:';
            app.offSpring = uieditfield(app.OtherSpecificationsPanel, 'numeric');
            app.label1_4 = uilabel(app.OtherSpecificationsPanel);
            app.label1_4.Text = 'Spring offset value.';
            app.safeheightlabel = uilabel(app.OtherSpecificationsPanel);
            app.safeheightlabel.Text = 'Safe Height:';
            app.label1_5 = uilabel(app.OtherSpecificationsPanel);
            app.label1_5.Text = 'Enter safe height for sonotrode raise to.';
            app.safeHeight = uieditfield(app.OtherSpecificationsPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GCodeGen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end